classdef SpirographGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        GridLayout5             matlab.ui.container.GridLayout
        SaveFigureButton        matlab.ui.control.Button
        RandomizeButton         matlab.ui.control.Button
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout9             matlab.ui.container.GridLayout
        Version11NIrAlmogLabel  matlab.ui.control.Label
        SpirographLabel         matlab.ui.control.Label
        MakeyourownmesmerizingGraphsLabel  matlab.ui.control.Label
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout8             matlab.ui.container.GridLayout
        EndAngleBox             matlab.ui.control.NumericEditField
        GridLayout7             matlab.ui.container.GridLayout
        kParameterBox           matlab.ui.control.NumericEditField
        GridLayout6             matlab.ui.container.GridLayout
        lParameterBox           matlab.ui.control.NumericEditField
        EndAngleSlider          matlab.ui.control.Slider
        EndAngleSliderLabel     matlab.ui.control.Label
        kParameterSlider        matlab.ui.control.Slider
        kParameterSliderLabel   matlab.ui.control.Label
        lParameterSlider        matlab.ui.control.Slider
        lParameterSliderLabel   matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EndAngleSlider
        function EndAngleSliderValueChanged(app, event)
            p.EndAngle = round(app.EndAngleSlider.Value * 1) / 1;
            app.EndAngleBox.Value = p.EndAngle;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Value changed function: kParameterSlider
        function kParameterSliderValueChanged(app, event)
            %p.k = round(app.kParameterSlider.Value * 0.1) / 0.1;
            app.kParameterBox.Value = app.kParameterSlider.Value;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Value changed function: lParameterSlider
        function lParameterSliderValueChanged(app, event)
            %p.l = round(app.lParameterSlider.Value * 0.1) / 0.1;
            app.lParameterBox.Value = app.lParameterSlider.Value;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Value changed function: kParameterBox
        function kParameterBoxValueChanged(app, event)
            Temp = app.kParameterBox.Value;
            kParameterBoxValue = max(app.kParameterSlider.Limits(1), min(Temp, app.kParameterSlider.Limits(2)));
            app.kParameterSlider.Value = kParameterBoxValue;
            app.kParameterBox.Value = kParameterBoxValue;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Value changed function: lParameterBox
        function lParameterBoxValueChanged(app, event)
            Temp = app.lParameterBox.Value;
            lParameterBoxValue = max(app.lParameterSlider.Limits(1), min(Temp, app.lParameterSlider.Limits(2)));
            app.lParameterSlider.Value = lParameterBoxValue;
            app.lParameterBox.Value = lParameterBoxValue;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
            
        end

        % Value changed function: EndAngleBox
        function EndAngleBoxValueChanged(app, event)
            Temp = app.EndAngleBox.Value;
            EndAngleBoxValue = max(app.EndAngleSlider.Limits(1), min(Temp, app.EndAngleSlider.Limits(2)));
            app.EndAngleSlider.Value = EndAngleBoxValue;
            app.EndAngleBox.Value = EndAngleBoxValue;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Button pushed function: RandomizeButton
        function RandomizeButtonPushed(app, event)
            app.lParameterSlider.Value = rand;
            app.kParameterSlider.Value = rand;
            app.EndAngleSlider.Value = randi(119);
            
            app.lParameterBox.Value = app.lParameterSlider.Value;
            app.kParameterBox.Value = app.kParameterSlider.Value;
            app.EndAngleBox.Value = app.EndAngleSlider.Value;
            
            p.l = app.lParameterSlider.Value;
            p.k = app.kParameterSlider.Value;
            p.EndAngle = app.EndAngleSlider.Value;
            
            points = SpirographFunction(p);

            darkBackground = rand(1, 3) * 0.2;
            brightColor = rand(1, 3) * 0.6 + 0.4;
            set(app.UIAxes, 'Color', darkBackground);
            plot(app.UIAxes ,points(1,:), points(2,:), 'Color', brightColor, 'LineWidth', 1.0);
            axis(app.UIAxes, 'equal');
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.DataAspectRatio = [1, 1, 1];
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            Note = ' | k Parameter  | ' + string(app.kParameterBox.Value) + ...
                   ' | l Parameter  | ' + string(app.lParameterBox.Value) + ...
                   ' | End Angle  | ' + string(app.EndAngleBox.Value);

            % Get the current date and time
            currentTime = clock;
            dateStr = datestr(currentTime, 'yyyymmdd_HHMMSS');
        
            % Save the current figure displayed in the UI Axis as a PNG image with the date and time as the file name
            fileName = [dateStr, '_figure.png'];
            exportgraphics(app.UIAxes, fileName, 'Resolution', 300);
        
            % Save a note file with the same name (date and time) and some text content
            noteFileName = [dateStr, '_note.txt'];
            noteContent = Note;
            fid = fopen(noteFileName, 'wt');
            fprintf(fid, '%s\n\r', noteContent);
            fclose(fid);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {393, 393};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.lParameterSliderLabel = uilabel(app.GridLayout3);
            app.lParameterSliderLabel.Text = 'lParameter';
            app.lParameterSlider = uislider(app.GridLayout3);
            app.lParameterSlider.Limits = [0 1];
            app.lParameterSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.lParameterSlider.ValueChangedFcn = createCallbackFcn(app, @lParameterSliderValueChanged, true);
            app.lParameterSlider.MinorTicks = [];
            app.kParameterSliderLabel = uilabel(app.GridLayout3);
            app.kParameterSliderLabel.Text = 'kParameter';
            app.kParameterSlider = uislider(app.GridLayout3);
            app.kParameterSlider.Limits = [0 1];
            app.kParameterSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.kParameterSlider.ValueChangedFcn = createCallbackFcn(app, @kParameterSliderValueChanged, true);
            app.kParameterSlider.MinorTicks = [];
            app.EndAngleSliderLabel = uilabel(app.GridLayout3);
            app.EndAngleSliderLabel.Text = 'End Angle';
            app.EndAngleSlider = uislider(app.GridLayout3);
            app.EndAngleSlider.Limits = [0 120];
            app.EndAngleSlider.ValueChangedFcn = createCallbackFcn(app, @EndAngleSliderValueChanged, true);
            app.EndAngleSlider.MinorTicks = [];
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.lParameterBox = uieditfield(app.GridLayout6, 'numeric');
            app.lParameterBox.ValueChangedFcn = createCallbackFcn(app, @lParameterBoxValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.kParameterBox = uieditfield(app.GridLayout7, 'numeric');
            app.kParameterBox.ValueChangedFcn = createCallbackFcn(app, @kParameterBoxValueChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.EndAngleBox = uieditfield(app.GridLayout8, 'numeric');
            app.EndAngleBox.ValueChangedFcn = createCallbackFcn(app, @EndAngleBoxValueChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.MakeyourownmesmerizingGraphsLabel = uilabel(app.GridLayout4);
            app.MakeyourownmesmerizingGraphsLabel.Text = 'Make your own mesmerizing Graphs!';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.SpirographLabel = uilabel(app.GridLayout9);
            app.SpirographLabel.Text = 'Spirograph';
            app.Version11NIrAlmogLabel = uilabel(app.GridLayout9);
            app.Version11NIrAlmogLabel.Text = {'Version 1.1'; 'NIr Almog'};
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.RandomizeButton = uibutton(app.GridLayout5, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeButtonPushed, true);
            app.RandomizeButton.Text = 'Randomize';
            app.SaveFigureButton = uibutton(app.GridLayout5, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpirographGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end